classdef iniciosesion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Hyperlink                  matlab.ui.control.Hyperlink
        NotienecuentaLabel         matlab.ui.control.Label
        IniciarsesinLabel          matlab.ui.control.Label
        UsuarioEditField           matlab.ui.control.EditField
        UsuarioLabel               matlab.ui.control.Label
        ContraseaEditField         matlab.ui.control.EditField
        ContraseaLabel             matlab.ui.control.Label
        EspecialidadDropDown       matlab.ui.control.DropDown
        EspecialidadDropDownLabel  matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        IniciarsesinButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarsesinButton
        function IniciarsesinButtonPushed(app, event)
   archivo = 'usuarios.xlsx';

    if ~isfile(archivo)
        uialert(app.UIFigure, 'No hay usuarios registrados.', 'Error');
        return;
    end

    datos = readcell(archivo);
    contenido = datos(2:end, :);  % Ignorar encabezado

    usuarioIngresado = app.UsuarioEditField.Value;
    contrasenaIngresada = app.ContraseaEditField.Value;
    rolIngresado = app.EspecialidadDropDown.Value;

    % Verificar que los 3 campos coincidan
    coincide = false;
    for i = 1:size(contenido, 1)
        usuario = contenido{i, 3};
        contrasena = contenido{i, 4};
        rol = contenido{i, 5};

        if isequal(usuario, usuarioIngresado) && ...
           isequal(contrasena, contrasenaIngresada) && ...
           isequal(rol, rolIngresado)
            coincide = true;
            break;
        end
    end

    if coincide
        if isequal(rolIngresado, "Médico")
             delete(app);
            nuevaApp = LoginApp(medico);              
        elseif isequal(rolIngresado, "Técnico de laboratorio")
            delete(app);
            nuevaApp = LoginApp(tecnico);              
        end
    else
        uialert(app.UIFigure, 'Usuario, contraseña o rol incorrecto.', 'Error');
    end
        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            delete(app);
            nuevaApp = LoginApp(registrarse);           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IniciarsesinButton = uibutton(app.UIFigure, 'push');
            app.IniciarsesinButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarsesinButtonPushed, true);
            app.IniciarsesinButton.Text = 'Iniciar sesión';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.EspecialidadDropDownLabel = uilabel(app.UIFigure);
            app.EspecialidadDropDownLabel.Text = 'Especialidad';
            app.EspecialidadDropDown = uidropdown(app.UIFigure);
            app.EspecialidadDropDown.Items = {'Medico', 'Técnico de laboratorio'};
            app.EspecialidadDropDown.Value = 'Medico';
            app.ContraseaLabel = uilabel(app.UIFigure);
            app.ContraseaLabel.Text = 'Contraseña ';
            app.ContraseaEditField = uieditfield(app.UIFigure, 'text');
            app.UsuarioLabel = uilabel(app.UIFigure);
            app.UsuarioLabel.Text = '  Usuario      ';
            app.UsuarioEditField = uieditfield(app.UIFigure, 'text');
            app.IniciarsesinLabel = uilabel(app.UIFigure);
            app.IniciarsesinLabel.Text = 'Iniciar sesión';
            app.NotienecuentaLabel = uilabel(app.UIFigure);
            app.NotienecuentaLabel.Text = '¿No tiene cuenta?';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = 'Regístrate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iniciosesion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end