classdef iniciosesionen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Hyperlink                  matlab.ui.control.Hyperlink
        YoudonthaveanaccountLabel  matlab.ui.control.Label
        SigninLabel                matlab.ui.control.Label
        UserEditField              matlab.ui.control.EditField
        UsuarioLabel               matlab.ui.control.Label
        PasswordEditField          matlab.ui.control.EditField
        ContraseaLabel             matlab.ui.control.Label
        SpecialtyDropDown          matlab.ui.control.DropDown
        SpecialtyDropDownLabel     matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        SigninButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SigninButton
        function SigninButtonPushed(app, event)
   archivo = 'usuarios.xlsx';

    if ~isfile(archivo)
        uialert(app.UIFigure, 'This user does not exist.', 'Error');
        return;
    end

    datos = readcell(archivo);
    contenido = datos(2:end, :);  % Ignorar encabezado

    usuarioIngresado = app.UserEditField.Value;
    contrasenaIngresada = app.PasswordEditField.Value;
    rolIngresado = app.SpecialtyDropDown.Value;

    % Verificar que los 3 campos coincidan
    coincide = false;
    for i = 1:size(contenido, 1)
        usuario = contenido{i, 3};
        contrasena = contenido{i, 4};
        rol = contenido{i, 5};

        if isequal(usuario, usuarioIngresado) && ...
           isequal(contrasena, contrasenaIngresada) && ...
           isequal(rol, rolIngresado)
            coincide = true;
            break;
        end
    end

    if coincide
        if isequal(rolIngresado, "Doctor")  
             delete(app);
            nuevaApp = LoginApp(medicoen);              
        elseif isequal(rolIngresado, "Laboratory technician")
            delete(app);
            nuevaApp = LoginApp(tecnicoen);              
        end
    else
        uialert(app.UIFigure, 'Wrong User, Password or Specialty.', 'Error');
    end
        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            delete(app);
            nuevaApp = LoginApp(registrarseen);           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SigninButton = uibutton(app.UIFigure, 'push');
            app.SigninButton.ButtonPushedFcn = createCallbackFcn(app, @SigninButtonPushed, true);
            app.SigninButton.Text = 'Sign in';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.SpecialtyDropDownLabel = uilabel(app.UIFigure);
            app.SpecialtyDropDownLabel.Text = 'Specialty';
            app.SpecialtyDropDown = uidropdown(app.UIFigure);
            app.SpecialtyDropDown.Items = {'Doctor', 'Laboratory technician'};
            app.SpecialtyDropDown.Value = 'Doctor';
            app.ContraseaLabel = uilabel(app.UIFigure);
            app.ContraseaLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.UIFigure, 'text');
            app.UsuarioLabel = uilabel(app.UIFigure);
            app.UsuarioLabel.Text = 'User';
            app.UserEditField = uieditfield(app.UIFigure, 'text');
            app.SigninLabel = uilabel(app.UIFigure);
            app.SigninLabel.Text = 'Sign in';
            app.YoudonthaveanaccountLabel = uilabel(app.UIFigure);
            app.YoudonthaveanaccountLabel.Text = 'You don''t have an account?';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = 'Register';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iniciosesionen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end