classdef iniciosesioneu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Hyperlink                    matlab.ui.control.Hyperlink
        EzdaukazukonturikLabel       matlab.ui.control.Label
        SesioahasiLabel              matlab.ui.control.Label
        ErabiltzaileaEditField       matlab.ui.control.EditField
        UsuarioLabel                 matlab.ui.control.Label
        PasahitzaEditField           matlab.ui.control.EditField
        ContraseaLabel               matlab.ui.control.Label
        EspezialitateaDropDown       matlab.ui.control.DropDown
        EspezialitateaDropDownLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        SesioahasiButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SesioahasiButton
        function SesioahasiButtonPushed(app, event)
   archivo = 'usuarios.xlsx';

    if ~isfile(archivo)
        uialert(app.UIFigure, 'Ez dago erabiltzaile hori erregistratia.', 'Errorea');
        return;
    end

    datos = readcell(archivo);
    contenido = datos(2:end, :);  % Ignorar encabezado

    usuarioIngresado = app.ErabiltzaileaEditField.Value;
    contrasenaIngresada = app.PasahitzaEditField.Value;
    rolIngresado = app.EspezialitateaDropDown.Value;

    % Verificar que los 3 campos coincidan
    coincide = false;
    for i = 1:size(contenido, 1)
        usuario = contenido{i, 3};
        contrasena = contenido{i, 4};
        rol = contenido{i, 5};

        if isequal(usuario, usuarioIngresado) && ...
           isequal(contrasena, contrasenaIngresada) && ...
           isequal(rol, rolIngresado)
            coincide = true;
            break;
        end
    end

    if coincide
        if isequal(rolIngresado, "Medikua")
             delete(app);
            nuevaApp = LoginApp(medicoeu);              
        elseif isequal(rolIngresado, "Laborategi teknikoa")
            delete(app);
            nuevaApp = LoginApp(tecnicoeu);              
        end
    else
        uialert(app.UIFigure, 'Erabiltzailea, Pasahitza edo Espezialitatea okerra.', 'Errorea');
    end
        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            delete(app);
            nuevaApp = LoginApp(registrarseeu);           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SesioahasiButton = uibutton(app.UIFigure, 'push');
            app.SesioahasiButton.ButtonPushedFcn = createCallbackFcn(app, @SesioahasiButtonPushed, true);
            app.SesioahasiButton.Text = 'Sesioa hasi';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.EspezialitateaDropDownLabel = uilabel(app.UIFigure);
            app.EspezialitateaDropDownLabel.Text = 'Espezialitatea';
            app.EspezialitateaDropDown = uidropdown(app.UIFigure);
            app.EspezialitateaDropDown.Items = {'Medikua', 'Laborategi teknikoa'};
            app.EspezialitateaDropDown.Value = 'Medikua';
            app.ContraseaLabel = uilabel(app.UIFigure);
            app.ContraseaLabel.Text = 'Pasahitza       ';
            app.PasahitzaEditField = uieditfield(app.UIFigure, 'text');
            app.UsuarioLabel = uilabel(app.UIFigure);
            app.UsuarioLabel.Text = 'Erabiltzailea   ';
            app.ErabiltzaileaEditField = uieditfield(app.UIFigure, 'text');
            app.SesioahasiLabel = uilabel(app.UIFigure);
            app.SesioahasiLabel.Text = 'Sesioa hasi';
            app.EzdaukazukonturikLabel = uilabel(app.UIFigure);
            app.EzdaukazukonturikLabel.Text = 'Ez daukazu konturik?';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = 'Erregistratu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iniciosesioneu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end