classdef medicoen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image3                         matlab.ui.control.Image
        Image2_4                       matlab.ui.control.Image
        TabGroup                       matlab.ui.container.TabGroup
        RegisterdataTab                matlab.ui.container.Tab
        PatientsdataregistrationLabel  matlab.ui.control.Label
        AgeEditField                   matlab.ui.control.NumericEditField
        EdadLabel                      matlab.ui.control.Label
        GenderDropDown                 matlab.ui.control.DropDown
        SexoLabel                      matlab.ui.control.Label
        SurnameEditField               matlab.ui.control.EditField
        SurnameLabel                   matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        NameLabel                      matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        DiagnoseTab                    matlab.ui.container.Tab
        DiagnoseButton                 matlab.ui.control.Button
        UploadphotoButton              matlab.ui.control.Button
        DiagnosethepatientLabel        matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        UIAxes                         matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
archivoXML = 'pacientes.xml';

% Verificar si el archivo existe y tiene contenido válido
if isfile(archivoXML) && dir(archivoXML).bytes > 0
    try
        doc = xmlread(archivoXML);
        root = doc.getDocumentElement;
    catch
        % Si el archivo existe pero está corrupto, lo recreamos
        doc = com.mathworks.xml.XMLUtils.createDocument('Pacientes');
        root = doc.getDocumentElement;
    end
else
    % Crear nuevo documento y nodo raíz <Pacientes>
    doc = com.mathworks.xml.XMLUtils.createDocument('Pacientes');
    root = doc.getDocumentElement;
end
   paciente = doc.createElement('Paciente');
    % Subnodos del paciente
    nombre = doc.createElement('Nombre');
    nombre.appendChild(doc.createTextNode(app.NameEditField.Value));
    paciente.appendChild(nombre);

    apellido = doc.createElement('Apellido');
    apellido.appendChild(doc.createTextNode(app.SurnameEditField.Value));
    paciente.appendChild(apellido);

    edad = doc.createElement('Edad');
    edad.appendChild(doc.createTextNode(num2str(app.AgeEditField.Value)));
    paciente.appendChild(edad);

    generoIngles = app.GenderDropDown.Value;
        switch generoIngles
            case 'Woman'
                generoEsp = 'Mujer';
            case 'Man'
                generoEsp = 'Hombre';
            case 'Other'
                generoEsp = 'Otro';
            otherwise
                generoEsp = 'No especificado';
        end
    sexo = doc.createElement('Sexo');
    sexo.appendChild(doc.createTextNode(generoEsp));
    paciente.appendChild(sexo);

    % Añadir <Paciente> al nodo raíz <Pacientes>
    root.appendChild(paciente);

    % Guardar archivo actualizado
    xmlwrite(archivoXML, doc);

    % Confirmación
    uialert(app.UIFigure, 'Paciente guardado correctamente en pacientes.xml', 'Éxito');
        end

        % Callback function: not associated with a component
        function UploadphotoButtonPushed(app, event)
    [archivo, ruta] = uigetfile({'*.jpg;*.png;*.jpeg','Imágenes (*.jpg, *.png)'});
    
    if isequal(archivo, 0)
        return; % Usuario canceló
    end

    img = imread(fullfile(ruta, archivo));
    imshow(img, 'Parent', app.UIAxes);
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            delete(app);
            nuevaApp = LoginApp(inicioen);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            delete(app);
            nuevaApp = LoginApp(inicioen);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RegisterdataTab = uitab(app.TabGroup);
            app.RegisterdataTab.Title = 'Register data';
            app.Image = uiimage(app.RegisterdataTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'logo.png';
            app.SaveButton = uibutton(app.RegisterdataTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.NameLabel = uilabel(app.RegisterdataTab);
            app.NameLabel.Text = 'Name     ';
            app.NameEditField = uieditfield(app.RegisterdataTab, 'text');
            app.SurnameLabel = uilabel(app.RegisterdataTab);
            app.SurnameLabel.Text = 'Surname ';
            app.SurnameEditField = uieditfield(app.RegisterdataTab, 'text');
            app.SexoLabel = uilabel(app.RegisterdataTab);
            app.SexoLabel.Text = 'Gender   ';
            app.GenderDropDown = uidropdown(app.RegisterdataTab);
            app.GenderDropDown.Items = {'Woman', 'Man', 'Other'};
            app.GenderDropDown.Value = 'Woman';
            app.EdadLabel = uilabel(app.RegisterdataTab);
            app.EdadLabel.Text = 'Age        ';
            app.AgeEditField = uieditfield(app.RegisterdataTab, 'numeric');
            app.PatientsdataregistrationLabel = uilabel(app.RegisterdataTab);
            app.PatientsdataregistrationLabel.Text = 'Patients data registration';
            app.DiagnoseTab = uitab(app.TabGroup);
            app.DiagnoseTab.Title = 'Diagnose';
            app.UIAxes = uiaxes(app.DiagnoseTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Image2 = uiimage(app.DiagnoseTab);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'logo.png';
            app.DiagnosethepatientLabel = uilabel(app.DiagnoseTab);
            app.DiagnosethepatientLabel.Text = 'Diagnose the patient';
            app.UploadphotoButton = uibutton(app.DiagnoseTab, 'push');
            app.UploadphotoButton.Text = 'Upload photo';
            app.DiagnoseButton = uibutton(app.DiagnoseTab, 'push');
            app.DiagnoseButton.Text = 'Diagnose';
            app.Image2_4 = uiimage(app.UIFigure);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 083416.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 085038 (1).png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = medicoen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end