classdef medicoeu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image3                         matlab.ui.control.Image
        Image2_4                       matlab.ui.control.Image
        TabGroup                       matlab.ui.container.TabGroup
        DatuakerregistratzeaTab        matlab.ui.container.Tab
        PazienteendatuendiagnostikoaLabel  matlab.ui.control.Label
        AdinaEditField                 matlab.ui.control.NumericEditField
        EdadLabel                      matlab.ui.control.Label
        SexuaDropDown                  matlab.ui.control.DropDown
        SexoLabel                      matlab.ui.control.Label
        AbizenaEditField               matlab.ui.control.EditField
        AbizenaEditFieldLabel          matlab.ui.control.Label
        IzenaEditField                 matlab.ui.control.EditField
        IzenaLabel                     matlab.ui.control.Label
        GordeButton                    matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        DiagnostikatuTab               matlab.ui.container.Tab
        PazientearendiagnostikoaLabel  matlab.ui.control.Label
        DIagnostikoaButton             matlab.ui.control.Button
        ArgazkiaigoButton              matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        UIAxes                         matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GordeButton
        function GordeButtonPushed(app, event)
archivoXML = 'pacientes.xml';

% Verificar si el archivo existe y tiene contenido válido
if isfile(archivoXML) && dir(archivoXML).bytes > 0
    try
        doc = xmlread(archivoXML);
        root = doc.getDocumentElement;
    catch
        % Si el archivo existe pero está corrupto, lo recreamos
        doc = com.mathworks.xml.XMLUtils.createDocument('Pacientes');
        root = doc.getDocumentElement;
    end
else
    % Crear nuevo documento y nodo raíz <Pacientes>
    doc = com.mathworks.xml.XMLUtils.createDocument('Pacientes');
    root = doc.getDocumentElement;
end
   paciente = doc.createElement('Paciente');
    % Subnodos del paciente
    nombre = doc.createElement('Nombre');
    nombre.appendChild(doc.createTextNode(app.IzenaEditField.Value));
    paciente.appendChild(nombre);

    apellido = doc.createElement('Apellido');
    apellido.appendChild(doc.createTextNode(app.AbizenaEditField.Value));
    paciente.appendChild(apellido);

    edad = doc.createElement('Edad');
    edad.appendChild(doc.createTextNode(num2str(app.AdinaEditField.Value)));
    paciente.appendChild(edad);

     generoEuskera = app.SexuaDropDown.Value;
        switch generoEuskera
            case 'Emakumea'
                generoEsp = 'Mujer';
            case 'Gizona'
                generoEsp = 'Hombre';
            case 'Beste'
                generoEsp = 'Otro';
            otherwise
                generoEsp = 'No especificado';
        end
    sexo = doc.createElement('Sexo');
    sexo.appendChild(doc.createTextNode(generoEsp));
    paciente.appendChild(sexo);

    % Añadir <Paciente> al nodo raíz <Pacientes>
    root.appendChild(paciente);

    % Guardar archivo actualizado
    xmlwrite(archivoXML, doc);

    % Confirmación
    uialert(app.UIFigure, 'Paciente guardado correctamente en pacientes.xml', 'Éxito');
        end

        % Callback function
        function ArgazkiaigoButtonPushed(app, event)
    [archivo, ruta] = uigetfile({'*.jpg;*.png;*.jpeg','Imágenes (*.jpg, *.png)'});
    
    if isequal(archivo, 0)
        return; % Usuario canceló
    end

    img = imread(fullfile(ruta, archivo));
    imshow(img, 'Parent', app.UIAxes);
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            delete(app);
            nuevaApp = LoginApp(inicioeu);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            delete(app);
            nuevaApp = LoginApp(inicioeu);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatuakerregistratzeaTab = uitab(app.TabGroup);
            app.DatuakerregistratzeaTab.Title = 'Datuak erregistratzea';
            app.Image = uiimage(app.DatuakerregistratzeaTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'logo.png';
            app.GordeButton = uibutton(app.DatuakerregistratzeaTab, 'push');
            app.GordeButton.ButtonPushedFcn = createCallbackFcn(app, @GordeButtonPushed, true);
            app.GordeButton.Text = 'Gorde';
            app.IzenaLabel = uilabel(app.DatuakerregistratzeaTab);
            app.IzenaLabel.Text = 'Izena    ';
            app.IzenaEditField = uieditfield(app.DatuakerregistratzeaTab, 'text');
            app.AbizenaEditFieldLabel = uilabel(app.DatuakerregistratzeaTab);
            app.AbizenaEditFieldLabel.Text = 'Abizena';
            app.AbizenaEditField = uieditfield(app.DatuakerregistratzeaTab, 'text');
            app.SexoLabel = uilabel(app.DatuakerregistratzeaTab);
            app.SexoLabel.Text = 'Sexua   ';
            app.SexuaDropDown = uidropdown(app.DatuakerregistratzeaTab);
            app.SexuaDropDown.Items = {'Emakumea', 'Gizona', 'Beste'};
            app.SexuaDropDown.Value = 'Emakumea';
            app.EdadLabel = uilabel(app.DatuakerregistratzeaTab);
            app.EdadLabel.Text = 'Adina   ';
            app.AdinaEditField = uieditfield(app.DatuakerregistratzeaTab, 'numeric');
            app.PazienteendatuendiagnostikoaLabel = uilabel(app.DatuakerregistratzeaTab);
            app.PazienteendatuendiagnostikoaLabel.Text = 'Pazienteen datuen diagnostikoa';
            app.DiagnostikatuTab = uitab(app.TabGroup);
            app.DiagnostikatuTab.Title = 'Diagnostikatu';
            app.UIAxes = uiaxes(app.DiagnostikatuTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Image2 = uiimage(app.DiagnostikatuTab);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'logo.png';
            app.ArgazkiaigoButton = uibutton(app.DiagnostikatuTab, 'push');
            app.ArgazkiaigoButton.Text = 'Argazkia igo';
            app.DIagnostikoaButton = uibutton(app.DiagnostikatuTab, 'push');
            app.DIagnostikoaButton.Text = 'DIagnostikoa';
            app.PazientearendiagnostikoaLabel = uilabel(app.DiagnostikatuTab);
            app.PazientearendiagnostikoaLabel.Text = 'Pazientearen diagnostikoa';
            app.Image2_4 = uiimage(app.UIFigure);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 083416.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 085038 (1).png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = medicoeu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end