classdef registrarseen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Hyperlink              matlab.ui.control.Hyperlink
        HaveyouanaccountLabel  matlab.ui.control.Label
        UserregistrationLabel  matlab.ui.control.Label
        SpecialtyDropDown      matlab.ui.control.DropDown
        SpecialtyLabel         matlab.ui.control.Label
        PasswordEditField      matlab.ui.control.EditField
        ContraseaLabel         matlab.ui.control.Label
        UserEditField          matlab.ui.control.EditField
        UsuarioLabel           matlab.ui.control.Label
        SurnameEditField       matlab.ui.control.EditField
        ApellidoLabel          matlab.ui.control.Label
        NameEditField          matlab.ui.control.EditField
        NombreLabel            matlab.ui.control.Label
        RegisterButton         matlab.ui.control.Button
        Image                  matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            delete(app);
            nuevaApp = LoginApp(iniciosesionen); 
           
        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
    archivo = 'usuarios.xlsx';

    % Crear la fila con el nuevo usuario
    nuevoUsuario = {
        app.NameEditField.Value, ...
        app.SurnameEditField.Value, ...
        app.UserEditField.Value, ...
        app.PasswordEditField.Value, ...
        app.SpecialtyDropDown.Value  % Nuevo campo
    };

    % Si el archivo ya existe, agregar al final
    if isfile(archivo)
        datos = readcell(archivo);
        datos(end+1, :) = nuevoUsuario;
    else
        % Crear archivo con encabezados
        datos = [{'Nombre', 'Apellido', 'Usuario', 'Contraseña', 'Especialidad'}; nuevoUsuario];
    end

    % Guardar en Excel
    writecell(datos, archivo);

       delete(app);
       nuevaApp = LoginApp(iniciosesionen); 
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.png';
            app.RegisterButton = uibutton(app.UIFigure, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Text = 'Register';
            app.NombreLabel = uilabel(app.UIFigure);
            app.NombreLabel.Text = 'Name      ';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.ApellidoLabel = uilabel(app.UIFigure);
            app.ApellidoLabel.Text = 'Surname ';
            app.SurnameEditField = uieditfield(app.UIFigure, 'text');
            app.UsuarioLabel = uilabel(app.UIFigure);
            app.UsuarioLabel.Text = 'User        ';
            app.UserEditField = uieditfield(app.UIFigure, 'text');
            app.ContraseaLabel = uilabel(app.UIFigure);
            app.ContraseaLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.UIFigure, 'text');
            app.SpecialtyLabel = uilabel(app.UIFigure);
            app.SpecialtyLabel.Text = 'Specialty  ';
            app.SpecialtyDropDown = uidropdown(app.UIFigure);
            app.SpecialtyDropDown.Items = {'Doctor', 'Laboratory technician'};
            app.SpecialtyDropDown.Value = 'Doctor';
            app.UserregistrationLabel = uilabel(app.UIFigure);
            app.UserregistrationLabel.Text = 'User registration';
            app.HaveyouanaccountLabel = uilabel(app.UIFigure);
            app.HaveyouanaccountLabel.Text = 'Have you an account?';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = 'Sign in';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = registrarseen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end