classdef registrarseeu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Hyperlink                       matlab.ui.control.Hyperlink
        KontuadaukazuLabel              matlab.ui.control.Label
        ErabiltzaileenerregistroaLabel  matlab.ui.control.Label
        EspezialitateaDropDown          matlab.ui.control.DropDown
        EspezialitateaDropDownLabel     matlab.ui.control.Label
        PasahitzaEditField              matlab.ui.control.EditField
        ContraseaLabel                  matlab.ui.control.Label
        ErabiltzaileaEditField          matlab.ui.control.EditField
        UsuarioLabel                    matlab.ui.control.Label
        AbizenaEditField                matlab.ui.control.EditField
        ApellidoLabel                   matlab.ui.control.Label
        IzenaEditField                  matlab.ui.control.EditField
        NombreLabel                     matlab.ui.control.Label
        ErregistratzeaButton            matlab.ui.control.Button
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            delete(app);
            nuevaApp = LoginApp(iniciosesioneu); 
           
        end

        % Button pushed function: ErregistratzeaButton
        function ErregistratzeaButtonPushed(app, event)
    archivo = 'usuarios.xlsx';

    % Crear la fila con el nuevo usuario
    nuevoUsuario = {
        app.IzenaEditField.Value, ...
        app.AbizenaEditField.Value, ...
        app.ErabiltzaileaEditField.Value, ...
        app.PasahitzaEditField.Value, ...
        app.EspezialitateaDropDown.Value  % Nuevo campo
    };

    % Si el archivo ya existe, agregar al final
    if isfile(archivo)
        datos = readcell(archivo);
        datos(end+1, :) = nuevoUsuario;
    else
        % Crear archivo con encabezados
        datos = [{'Nombre', 'Apellido', 'Usuario', 'Contraseña', 'Especialidad'}; nuevoUsuario];
    end

    % Guardar en Excel
    writecell(datos, archivo);

       delete(app);
       nuevaApp = LoginApp(iniciosesioneu); 
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.png';
            app.ErregistratzeaButton = uibutton(app.UIFigure, 'push');
            app.ErregistratzeaButton.ButtonPushedFcn = createCallbackFcn(app, @ErregistratzeaButtonPushed, true);
            app.ErregistratzeaButton.Text = 'Erregistratzea';
            app.NombreLabel = uilabel(app.UIFigure);
            app.NombreLabel.Text = 'Izena           ';
            app.IzenaEditField = uieditfield(app.UIFigure, 'text');
            app.ApellidoLabel = uilabel(app.UIFigure);
            app.ApellidoLabel.Text = 'Abizena        ';
            app.AbizenaEditField = uieditfield(app.UIFigure, 'text');
            app.UsuarioLabel = uilabel(app.UIFigure);
            app.UsuarioLabel.Text = 'Erabiltzailea  ';
            app.ErabiltzaileaEditField = uieditfield(app.UIFigure, 'text');
            app.ContraseaLabel = uilabel(app.UIFigure);
            app.ContraseaLabel.Text = 'Pasahitza      ';
            app.PasahitzaEditField = uieditfield(app.UIFigure, 'text');
            app.EspezialitateaDropDownLabel = uilabel(app.UIFigure);
            app.EspezialitateaDropDownLabel.Text = 'Espezialitatea';
            app.EspezialitateaDropDown = uidropdown(app.UIFigure);
            app.EspezialitateaDropDown.Items = {'Medikua', 'Laborategi teknikoa'};
            app.EspezialitateaDropDown.Value = 'Medikua';
            app.ErabiltzaileenerregistroaLabel = uilabel(app.UIFigure);
            app.ErabiltzaileenerregistroaLabel.Text = 'Erabiltzaileen erregistroa';
            app.KontuadaukazuLabel = uilabel(app.UIFigure);
            app.KontuadaukazuLabel.Text = 'Kontua daukazu?';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = 'Sesioa hasi';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = registrarseeu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end