classdef tecnico < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DiagnosticarButton          matlab.ui.control.Button
        SubirfotoButton             matlab.ui.control.Button
        Image3                      matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        TabGroup                    matlab.ui.container.TabGroup
        DiagnosticarTab             matlab.ui.container.Tab
        DiagnsticodelpacienteLabel  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SubirfotoButtonPushed(app, event)
    [archivo, ruta] = uigetfile({'*.jpg;*.png;*.jpeg','Imágenes (*.jpg, *.png)'});
    
    if isequal(archivo, 0)
        return; % Usuario canceló
    end

    img = imread(fullfile(ruta, archivo));
    imshow(img, 'Parent', app.UIAxes);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            delete(app);
            nuevaApp = LoginApp(inicio);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DiagnosticarTab = uitab(app.TabGroup);
            app.DiagnosticarTab.Title = 'Diagnosticar';
            app.UIAxes = uiaxes(app.DiagnosticarTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Image = uiimage(app.DiagnosticarTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.DiagnsticodelpacienteLabel = uilabel(app.DiagnosticarTab);
            app.DiagnsticodelpacienteLabel.Text = 'Diagnóstico del paciente';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 083416.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 085038 (1).png');
            app.SubirfotoButton = uibutton(app.UIFigure, 'push');
            app.SubirfotoButton.Text = 'Subir foto';
            app.DiagnosticarButton = uibutton(app.UIFigure, 'push');
            app.DiagnosticarButton.Text = 'Diagnosticar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tecnico

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end